classdef App_General_Result < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ResultsUIFigure              matlab.ui.Figure
        SaveMenu                     matlab.ui.container.Menu
        TableMenu                    matlab.ui.container.Menu
        DynamicBehaviorMenu          matlab.ui.container.Menu
        FFTMenu                      matlab.ui.container.Menu
        ParametersMenu               matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        DisplacementTab              matlab.ui.container.Tab
        UIAxes_Displacement          matlab.ui.control.UIAxes
        StraininXaxisTab             matlab.ui.container.Tab
        UIAxes_StrainX               matlab.ui.control.UIAxes
        StraininYaxisTab             matlab.ui.container.Tab
        UIAxes_StrainY               matlab.ui.control.UIAxes
        StressinXaxisTab             matlab.ui.container.Tab
        UIAxes_StressX               matlab.ui.control.UIAxes
        StressinYaxisTab             matlab.ui.container.Tab
        UIAxes_StressY               matlab.ui.control.UIAxes
        PressureTab                  matlab.ui.container.Tab
        UIAxes_Pressure              matlab.ui.control.UIAxes
        FFTTab                       matlab.ui.container.Tab
        UIAxes_FFT                   matlab.ui.control.UIAxes
        UITable                      matlab.ui.control.Table
        ParametersPanel              matlab.ui.container.Panel
        uzmLabel                     matlab.ui.control.Label
        MaxStaticDisplacementmLabel  matlab.ui.control.Label
        w_din_max                    matlab.ui.control.NumericEditField
        w_sta_max                    matlab.ui.control.NumericEditField
        K1Label                      matlab.ui.control.Label
        K3Label                      matlab.ui.control.Label
        k1                           matlab.ui.control.NumericEditField
        k3                           matlab.ui.control.NumericEditField
        LinearPeriodTLsLabel         matlab.ui.control.Label
        NonlinearPeriodTNLsLabel     matlab.ui.control.Label
        TNLTLTNLsLabel               matlab.ui.control.Label
        linearperiod                 matlab.ui.control.NumericEditField
        nonlinearperiod              matlab.ui.control.NumericEditField
        totalperiod                  matlab.ui.control.NumericEditField
        tdsLabel                     matlab.ui.control.Label
        tmsLabel                     matlab.ui.control.Label
        pmaxPaLabel                  matlab.ui.control.Label
        pminPaLabel                  matlab.ui.control.Label
        idKPamLabel                  matlab.ui.control.Label
        id                           matlab.ui.control.NumericEditField
        imKPamLabel                  matlab.ui.control.Label
        im                           matlab.ui.control.NumericEditField
        DecaimentCoefficientLabel    matlab.ui.control.Label
        dec_coef                     matlab.ui.control.NumericEditField
        td                           matlab.ui.control.NumericEditField
        tm                           matlab.ui.control.NumericEditField
        pmax                         matlab.ui.control.NumericEditField
        pmin                         matlab.ui.control.NumericEditField
        UITable2                     matlab.ui.control.Table
    end

    
    properties (Access = public)
        
        % ============================================================%
        
        % Properties to access another window or pass values
        tnt         Class_TNT
        plate       Class_Plate
        analysis    Class_Analysis
        blast       Class_Blast
        k1k3        Class_K1K3
        disp        Class_Disp
        stress      Class_Stress
        strain      Class_Strain
        result      Class_Result
        period      Class_Period
        static      Class_Static
        
        appblast
        appInfo
        
    end
    
    methods (Access = private)
        
        function saveFile(app,filename)
            
            % Open file with given file name for writing
            fid = fopen(filename,'wt+');
            
            % Writing values about the plate
            fprintf(fid, '%d\n', app.k1.Value);
            fprintf(fid, '%d\n', app.k3.Value);
            fprintf(fid, '%d\n', app.w_din_max.Value);
            fprintf(fid, '%d\n', app.w_sta_max.Value);
            fprintf(fid, '%d\n', app.linearperiod.Value);
            fprintf(fid, '%d\n', app.nonlinearperiod.Value);
            fprintf(fid, '%d\n', app.totalperiod.Value);
            
            % Writing values about the blast wave
            fprintf(fid, '%d\n', app.td.Value);
            fprintf(fid, '%d\n', app.tm.Value);
            fprintf(fid, '%d\n', app.pmax.Value);
            fprintf(fid, '%d\n', app.pmin.Value);
            fprintf(fid, '%d\n', app.id.Value);
            fprintf(fid, '%d\n', app.im.Value);
            fprintf(fid, '%d\n', app.dec_coef.Value);
            
            % Close file
            fclose(fid);
            
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ParametersMenu
        function ParametersMenuSelected(app, event)
            filterspec = {'*.txt'};
            [filename,pathname] = uiputfile(filterspec,'Blast Program - Output file');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                saveFile(app,fullname)
            end 
        end

        % Menu selected function: DynamicBehaviorMenu
        function DynamicBehaviorMenuSelected(app, event)
            S = app.UITable.Data;
            filterspec = {'*.xlsx'};
            [filename,pathname] = uiputfile(filterspec,'Blast Program - Export to Excel');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                xlswrite(fullname,S,1)
            end
        end

        % Menu selected function: FFTMenu
        function FFTMenuSelected(app, event)
            S = app.UITable2.Data;
            filterspec = {'*.xlsx'};
            [filename,pathname] = uiputfile(filterspec,'Blast Program - Export to Excel');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                xlswrite(fullname,S,1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ResultsUIFigure = uifigure('Visible', 'off');
            app.ResultsUIFigure.Name = 'Results';
            app.SaveMenu = uimenu(app.ResultsUIFigure);
            app.SaveMenu.Text = 'Save';
            app.TableMenu = uimenu(app.SaveMenu);
            app.TableMenu.Text = 'Table';
            app.DynamicBehaviorMenu = uimenu(app.TableMenu);
            app.DynamicBehaviorMenu.MenuSelectedFcn = createCallbackFcn(app, @DynamicBehaviorMenuSelected, true);
            app.DynamicBehaviorMenu.Text = 'Dynamic Behavior';
            app.FFTMenu = uimenu(app.TableMenu);
            app.FFTMenu.MenuSelectedFcn = createCallbackFcn(app, @FFTMenuSelected, true);
            app.FFTMenu.Text = 'FFT';
            app.ParametersMenu = uimenu(app.SaveMenu);
            app.ParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ParametersMenuSelected, true);
            app.ParametersMenu.Text = 'Parameters';
            app.TabGroup = uitabgroup(app.ResultsUIFigure);
            app.DisplacementTab = uitab(app.TabGroup);
            app.DisplacementTab.Title = 'Displacement';
            app.UIAxes_Displacement = uiaxes(app.DisplacementTab);
            title(app.UIAxes_Displacement, '')
            xlabel(app.UIAxes_Displacement, 'time (s)')
            ylabel(app.UIAxes_Displacement, 'u_z (m)')
            app.StraininXaxisTab = uitab(app.TabGroup);
            app.StraininXaxisTab.Title = 'Strain in X axis';
            app.UIAxes_StrainX = uiaxes(app.StraininXaxisTab);
            title(app.UIAxes_StrainX, '')
            xlabel(app.UIAxes_StrainX, 'time (s)')
            ylabel(app.UIAxes_StrainX, '\epsilon_x_x')
            app.StraininYaxisTab = uitab(app.TabGroup);
            app.StraininYaxisTab.Title = 'Strain in Y axis';
            app.UIAxes_StrainY = uiaxes(app.StraininYaxisTab);
            title(app.UIAxes_StrainY, '')
            xlabel(app.UIAxes_StrainY, 'time (s)')
            ylabel(app.UIAxes_StrainY, '\epsilon_y_y')
            app.StressinXaxisTab = uitab(app.TabGroup);
            app.StressinXaxisTab.Title = 'Stress in X axis';
            app.UIAxes_StressX = uiaxes(app.StressinXaxisTab);
            title(app.UIAxes_StressX, '')
            xlabel(app.UIAxes_StressX, 'time (s)')
            ylabel(app.UIAxes_StressX, '\sigma_x_x (MPa)')
            app.StressinYaxisTab = uitab(app.TabGroup);
            app.StressinYaxisTab.Title = 'Stress in Y axis';
            app.UIAxes_StressY = uiaxes(app.StressinYaxisTab);
            title(app.UIAxes_StressY, '')
            xlabel(app.UIAxes_StressY, 'time (s)')
            ylabel(app.UIAxes_StressY, '\sigma_y_y (MPa)')
            app.PressureTab = uitab(app.TabGroup);
            app.PressureTab.Title = 'Pressure';
            app.UIAxes_Pressure = uiaxes(app.PressureTab);
            title(app.UIAxes_Pressure, '')
            xlabel(app.UIAxes_Pressure, 'time (s)')
            ylabel(app.UIAxes_Pressure, 'pressure (Pa)')
            app.FFTTab = uitab(app.TabGroup);
            app.FFTTab.Title = 'FFT';
            app.UIAxes_FFT = uiaxes(app.FFTTab);
            title(app.UIAxes_FFT, '')
            xlabel(app.UIAxes_FFT, {'1 /  ω_N _L '; ''})
            ylabel(app.UIAxes_FFT, 'FFT')
            app.UITable = uitable(app.ResultsUIFigure);
            app.UITable.ColumnName = {'t (s)'; 'uz (m)'; 'ε xx'; 'ε yy'; 'σ xx (MPa)'; 'σ yy (MPa)'; 'P (Pa)'};
            app.UITable.RowName = {};
            app.ParametersPanel = uipanel(app.ResultsUIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.uzmLabel = uilabel(app.ParametersPanel);
            app.uzmLabel.Text = 'uz (m)';
            app.MaxStaticDisplacementmLabel = uilabel(app.ParametersPanel);
            app.MaxStaticDisplacementmLabel.Text = 'Max Static Displacement (m):';
            app.w_din_max = uieditfield(app.ParametersPanel, 'numeric');
            app.w_din_max.Editable = 'off';
            app.w_sta_max = uieditfield(app.ParametersPanel, 'numeric');
            app.w_sta_max.Editable = 'off';
            app.K1Label = uilabel(app.ParametersPanel);
            app.K1Label.Text = 'K1: ';
            app.K3Label = uilabel(app.ParametersPanel);
            app.K3Label.Text = 'K3:';
            app.k1 = uieditfield(app.ParametersPanel, 'numeric');
            app.k1.Editable = 'off';
            app.k3 = uieditfield(app.ParametersPanel, 'numeric');
            app.k3.Editable = 'off';
            app.LinearPeriodTLsLabel = uilabel(app.ParametersPanel);
            app.LinearPeriodTLsLabel.Text = 'Linear Period - TL (s):';
            app.NonlinearPeriodTNLsLabel = uilabel(app.ParametersPanel);
            app.NonlinearPeriodTNLsLabel.Text = 'Nonlinear Period - TNL (s):';
            app.TNLTLTNLsLabel = uilabel(app.ParametersPanel);
            app.TNLTLTNLsLabel.Text = 'TNL / (TL + TNL) (s):';
            app.linearperiod = uieditfield(app.ParametersPanel, 'numeric');
            app.linearperiod.Editable = 'off';
            app.nonlinearperiod = uieditfield(app.ParametersPanel, 'numeric');
            app.nonlinearperiod.Editable = 'off';
            app.totalperiod = uieditfield(app.ParametersPanel, 'numeric');
            app.totalperiod.Editable = 'off';
            app.tdsLabel = uilabel(app.ParametersPanel);
            app.tdsLabel.Text = 'td (s):';
            app.tmsLabel = uilabel(app.ParametersPanel);
            app.tmsLabel.Text = 'tm (s):';
            app.pmaxPaLabel = uilabel(app.ParametersPanel);
            app.pmaxPaLabel.Text = 'pmax (Pa):';
            app.pminPaLabel = uilabel(app.ParametersPanel);
            app.pminPaLabel.Text = 'pmin (Pa):';
            app.idKPamLabel = uilabel(app.ParametersPanel);
            app.idKPamLabel.Text = 'id (KPa.m):';
            app.id = uieditfield(app.ParametersPanel, 'numeric');
            app.id.Editable = 'off';
            app.imKPamLabel = uilabel(app.ParametersPanel);
            app.imKPamLabel.Text = 'im (KPa.m):';
            app.im = uieditfield(app.ParametersPanel, 'numeric');
            app.im.Editable = 'off';
            app.DecaimentCoefficientLabel = uilabel(app.ParametersPanel);
            app.DecaimentCoefficientLabel.Text = 'Decaiment Coefficient:';
            app.dec_coef = uieditfield(app.ParametersPanel, 'numeric');
            app.dec_coef.Editable = 'off';
            app.td = uieditfield(app.ParametersPanel, 'numeric');
            app.td.Editable = 'off';
            app.tm = uieditfield(app.ParametersPanel, 'numeric');
            app.tm.Editable = 'off';
            app.pmax = uieditfield(app.ParametersPanel, 'numeric');
            app.pmax.Editable = 'off';
            app.pmin = uieditfield(app.ParametersPanel, 'numeric');
            app.pmin.Editable = 'off';
            app.UITable2 = uitable(app.ResultsUIFigure);
            app.UITable2.ColumnName = {'1 / ω'; 'FFT'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.ResultsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_General_Result

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ResultsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ResultsUIFigure)
        end
    end
end