classdef App_Blast < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DYNAblastUIFigure              matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutSoftwareMenu              matlab.ui.container.Menu
        TNTPanel                       matlab.ui.container.Panel
        DataBaseLabel_3                matlab.ui.control.Label
        app_im                         matlab.ui.control.NumericEditField
        NegativeImpulsePasLabel        matlab.ui.control.Label
        app_id                         matlab.ui.control.NumericEditField
        PositiveImpulsePasLabel        matlab.ui.control.Label
        app_td                         matlab.ui.control.NumericEditField
        app_pmin                       matlab.ui.control.NumericEditField
        app_pmax                       matlab.ui.control.NumericEditField
        PositivetimesLabel             matlab.ui.control.Label
        MaxUnderpressurePaLabel        matlab.ui.control.Label
        MaxSobrepressurePaLabel        matlab.ui.control.Label
        app_Z                          matlab.ui.control.NumericEditField
        app_W                          matlab.ui.control.NumericEditField
        ScalarDistanceZkgm13Label      matlab.ui.control.Label
        CharacteristicsLabel           matlab.ui.control.Label
        TotalMasskgLabel               matlab.ui.control.Label
        TypeExplosion                  matlab.ui.container.ButtonGroup
        HemisphericalButton            matlab.ui.control.RadioButton
        SphericalButton                matlab.ui.control.RadioButton
        TypeofExplosionLabel           matlab.ui.control.Label
        DataBase                       matlab.ui.container.ButtonGroup
        AbacoAnaCalibrationButton      matlab.ui.control.RadioButton
        ExperimentalDataButton         matlab.ui.control.RadioButton
        AbacoRigbyCalibrationButton_2  matlab.ui.control.RadioButton
        AnalysisPanel                  matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        GeneralAnalysisTab             matlab.ui.container.Tab
        TimeAnalysis                   matlab.ui.control.NumericEditField
        timesLabel                     matlab.ui.control.Label
        TimeofAnalysisLabel            matlab.ui.control.Label
        NegativePhase                  matlab.ui.container.ButtonGroup
        NoButton_2                     matlab.ui.control.RadioButton
        YesButton_2                    matlab.ui.control.RadioButton
        NegativePhaseLabel             matlab.ui.control.Label
        NonlinearAnalisys              matlab.ui.container.ButtonGroup
        NoButton                       matlab.ui.control.RadioButton
        YesButton                      matlab.ui.control.RadioButton
        NonlinearAnalysisLabel         matlab.ui.control.Label
        AdvancedAnalysisTab            matlab.ui.container.Tab
        ButtonGroup                    matlab.ui.container.ButtonGroup
        W100to1000kgButton             matlab.ui.control.RadioButton
        W1to10kgButton                 matlab.ui.control.RadioButton
        CaseofAnalysisLabel            matlab.ui.control.Label
        Case                           matlab.ui.control.DropDown
        interval                       matlab.ui.control.NumericEditField
        txt_interval_case2             matlab.ui.control.Label
        W_final                        matlab.ui.control.NumericEditField
        txt_mass_case2                 matlab.ui.control.Label
        PlatePanel                     matlab.ui.container.Panel
        app_rho                        matlab.ui.control.NumericEditField
        app_E                          matlab.ui.control.NumericEditField
        app_nu                         matlab.ui.control.NumericEditField
        MaterialsdensitykgmLabel       matlab.ui.control.Label
        YoungsModulusENmLabel          matlab.ui.control.Label
        PoissonscoefficientLabel       matlab.ui.control.Label
        PhisicalCharacteristicsLabel   matlab.ui.control.Label
        app_h                          matlab.ui.control.NumericEditField
        ThicknessmLabel                matlab.ui.control.Label
        app_beta                       matlab.ui.control.NumericEditField
        app_a                          matlab.ui.control.NumericEditField
        betaLabel                      matlab.ui.control.Label
        LengthatxaxismLabel            matlab.ui.control.Label
        GeometricCharacteristicsLabel  matlab.ui.control.Label
        BoundaryConditionsLabel        matlab.ui.control.Label
        SupportMembrane                matlab.ui.control.DropDown
        SupportPlate                   matlab.ui.control.DropDown
        RunButton_Adv                  matlab.ui.control.Button
        RunButton_General              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        % Properties to complete a data table
        Data
        
    end
    
    properties (Access = public)
        
        % Properties to access another window or pass values
        tnt         Class_TNT           % TNT's properties
        plate       Class_Plate         % Plate's properties
        analysis    Class_Analysis      % Type of Analysis
        adv         Class_AdvAnalysis   % Advanced properties
        prt         Class_Parameters    % Advanced's parameters
        blast       Class_Blast         % Blast load parameters
        k1k3        Class_K1K3          % Plate's parameters
        disp        Class_Disp          % Structure's displacement
        ft          Class_FFT           % Structure's FFT
        calcadv     Class_Advanced      % Class to calculate
        stress      Class_Stress        % Structure's stress
        strain      Class_Strain        % Structure's strain
        result      Class_Result        % Structure's results
        period      Class_Period        % Structure's periods
        static      Class_Static        % Structure's static
                
        % Vector about results/parameters
        values_tnt      = [];           % TNT's properties
        values_plate    = [];           % Plate's properties
        values_analysis = [];           % Type of Analysis
        values_adv      = [];           % Advanced properties
        values_prt      = [];           % Advanced's parameters
        values_blast    = [];           % Blast load parameters
        values_k1k3     = [];           % Plate's parameters
        values_disp     = [];           % Structure's displacement
        values_fft      = [];           % Structure's FFT
        values_calcadv  = [];           % Class to calculate
        values_stress   = [];           % Structure's stress
        values_strain   = [];           % Structure's strain
        values_result   = [];           % Structure's results
        values_period   = [];           % Structure's periods
        values_static   = [];           % Structure's static
                
        % Parameters to pass informations between windows
        appInfo                         % Window about software
        appGeneralResult                % Window about general button
        appAdvanced                     % Window about advanced button
        
    end
    
    methods (Access = public)
        
        % =============================================================== %
        % =============================================================== %
        
        % Function to verificate the values of user's options
        function [sup, type_sup, type, nonlinear, negative, option, parameter] = ...
                Avaliating(~, ap1, ap2, ap3, ap4, ap5, ap6, ap7)
            
            % Avaliating the type of plate's support
            switch ap1
                case 'Simple Support'
                    sup = 0;
                case 'Campled Support'
                    sup = 1;
            end
            
            % Avaliating the type of membrane            
            switch ap2
                case 'Immovable'
                    type_sup = 1;
                case 'Movable'
                    type_sup = 2;
                case 'Stress Free'
                    type_sup = 3;
            end
            
            % Avaliating the type of explosion
            switch ap3
                case 'Hemispherical'
                    type = 1;
                case 'Spherical'
                    type = 2;
            end
            
            % Avaliating the nonlinear analysis
            switch ap4
                case 'Yes'
                    nonlinear = 1;
                case 'No'
                    nonlinear = 0;
            end
            
            % Avaliating the consideration of negative phase
            switch ap5
                case 'Yes'
                    negative = 1;
                case 'No'
                    negative = 0;
            end
            
            % Avaliating the type blast wave analysis
            switch ap6
                case 'Abaco - Rigby Calibration'
                    option = 2;
                case 'Abaco - Ana Calibration'
                    option = 3;
                case 'Experimental Data'
                    option = 4;
            end
            
            % Avaliating the advanced analysis
            switch ap7
                case 'Case 1 - Z x uz / h'
                    parameter = 1;
                case 'Case 2 - W x uz / h'
                    parameter = 2;
                case 'Case 3 - td / TL x DAF - Variating W'
                    parameter = 3;
                case 'Case 4 - td / TL x uz / h'
                    parameter = 4;
                case 'Case 5 - td / TNL x uz / h'
                    parameter = 5;
                case 'Case 6 - uz / h x stress'
                    parameter = 6;
                case 'Case 7 - td / TL x DAF - Variating Z'
                    parameter = 7;
                case 'Case 8 - General Equation'
                    parameter = 8;
            end
                        
        end
        
        % =============================================================== %
        % =============================================================== %
        
        % Function to create save file
        function saveFile(app,filename)
            
            % ----------------------------------------------------------- %
            
            % Colecting values
            
            [sup, type_sup, type, nonlinear, negative, option, ~] = ...
                app.Avaliating...
                (app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text,...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            % ----------------------------------------------------------- %
            
            % Open file with given file name for writing
            
            fid = fopen(filename,'wt+');
            
            % ----------------------------------------------------------- %
            
            % Writing boundary conditions
            
            fprintf(fid, '%d\n', type_sup);
            fprintf(fid, '%d\n', sup);
            
            % ----------------------------------------------------------- %
            
            % Writing values about the plate
            
            fprintf(fid, '%d\n', app.app_a.Value);
            fprintf(fid, '%d\n', app.app_beta.Value);
            fprintf(fid, '%d\n', app.app_h.Value);
            fprintf(fid, '%d\n', app.app_nu.Value);
            fprintf(fid, '%d\n', app.app_E.Value);
            fprintf(fid, '%d\n', app.app_rho.Value);
            
            % ----------------------------------------------------------- %
            
            % Writing values about TNT
            
            fprintf(fid, '%d\n', app.app_W.Value);
            fprintf(fid, '%d\n', app.app_Z.Value);
            fprintf(fid, '%d\n', type);
            fprintf(fid, '%d\n', option);
            fprintf(fid, '%d\n', app.app_pmax.Value);
            fprintf(fid, '%d\n', app.app_pmin.Value);
            fprintf(fid, '%d\n', app.app_td.Value);
            fprintf(fid, '%d\n', app.app_id.Value);
            fprintf(fid, '%d\n', app.app_im.Value);
            
            % ----------------------------------------------------------- %
            
            % Writing values about analysis
            
            fprintf(fid, '%d\n', nonlinear);
            fprintf(fid, '%d\n', negative);
            fprintf(fid, '%d\n', app.TimeAnalysis.Value);
            
            % ----------------------------------------------------------- %
            
            % Close file
            
            fclose(fid);
            
        end
        
        % ============================================================%
        % =============================================================== %
        
        % Function to create a save file for general equation
        function saveEquation(~,filename, A, B, C, D, E, F)
        
            % ----------------------------------------------------------- %
            
            % Open file with given file name for writing
            
            fid = fopen(filename,'wt+');
            
            % ----------------------------------------------------------- %
            
            % Writing principal equation
            
            fprintf(fid,'General Equation for curves \n');
            fprintf(fid, ...
                'u_z / h = A * Z^5 + B * Z^4 + C * Z^3 + D * Z^2 + E * Z + F \n');
            fprintf(fid,'\n');
            
            % ----------------------------------------------------------- %
            
            % Writing coefficient equation    
            
            fprintf(fid, 'Coefficients Equations \n');
            fprintf(fid, ['A = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [A(1),A(2),A(3),A(4),A(5),A(6),A(7)]);
            fprintf(fid, ['B = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [B(1),B(2),B(3),B(4),B(5),B(6),B(7)]);
            fprintf(fid, ['C = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [C(1),C(2),C(3),C(4),C(5),C(6),C(7)]);
            fprintf(fid, ['D = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [D(1),D(2),D(3),D(4),D(5),D(6),D(7)]);
            fprintf(fid, ['E = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [E(1),E(2),E(3),E(4),E(5),E(6),E(7)]);
            fprintf(fid, ['F = %2.10f * W^6 + %2.10f * W^5 + %2.10f * W^4 + ' ...
                '%2.10f * W^3 + %2.10f * W^2 + %2.10f * W + %2.10f \n'], ...
                [F(1),F(2),F(3),F(4),F(5),F(6),F(7)]);
            fprintf(fid,'\n');
            
            fprintf(fid,'Legend: \n');
            fprintf(fid,'Z: Scalar Distance [m / (kg)^(1/3)] \n');
            fprintf(fid,'W: TNT Mass [kg] \n');
                        
            % ----------------------------------------------------------- %
            
            % Close file
            
            fclose(fid);
        
        end
        
        % ============================================================%
        % ============================================================%
        
        % Function to create an open file
        function openFile(app,filename)
            
            % ----------------------------------------------------------- %
            
            % Open file with given file name for reading
            
            fid = fopen(filename,'rt');
            
            % ----------------------------------------------------------- %
            
            % Reading values
            
            type_sup                = fscanf(fid,'%f',1);
            sup                     = fscanf(fid,'%f',1);
            
            app.app_a.Value         = abs(fscanf(fid,'%f',1));
            app.app_beta.Value      = abs(fscanf(fid,'%f',1));
            app.app_h.Value         = abs(fscanf(fid,'%f',1));
            app.app_nu.Value        = abs(fscanf(fid,'%f',1));
            app.app_E.Value         = abs(fscanf(fid,'%f',1));
            app.app_rho.Value       = abs(fscanf(fid,'%f',1));
            
            app.app_W.Value         = abs(fscanf(fid,'%f',1));
            app.app_Z.Value         = abs(fscanf(fid,'%f',1));
            type                    = abs(fscanf(fid,'%f',1));
            option                  = abs(fscanf(fid,'%f',1));
            app.app_pmax.Value      = abs(fscanf(fid,'%f',1));
            app.app_pmin.Value      = abs(fscanf(fid,'%f',1));
            app.app_td.Value        = abs(fscanf(fid,'%f',1));
            app.app_id.Value        = abs(fscanf(fid,'%f',1));
            app.app_im.Value        = abs(fscanf(fid,'%f',1));
            
            nonlinear               = abs(fscanf(fid,'%f',1));
            negative                = abs(fscanf(fid,'%f',1));
            app.TimeAnalysis.Value  = abs(fscanf(fid,'%f',1));
            
            % ----------------------------------------------------------- %
            
            % Verificating conditions
            
            if type_sup == 1
                app.SupportMembrane.Value = 'Immovable';
            elseif type_sup == 2
                app.SupportMembrane.Value = 'Movable';
            elseif type_sup == 3
                app.SupportMembrane.Value = 'Stress Free';
            else
                app.SupportMembrane.Value = 'Immovable';
                app.Message('membrane support', 'immovable');
            end
            
            if sup == 0
                app.SupportPlate.Value = 'Simple Support';
            elseif sup == 1
                app.SupportPlate.Value = 'Campled Support';
            else
                app.SupportPlate.Value = 'Simple Support';
                app.Message('plate support', 'simple support');
            end
            
            if type == 1
                app.TypeExplosion.SelectedObject.Text = 'Hemispherical';
            elseif type == 2
                app.TypeExplosion.SelectedObject.Text = 'Spherical';
            else
                app.TypeExplosion.SelectedObject.Text = 'Hemispherical';
                app.Message('blast wave', 'hemispherical');
            end
            
            if nonlinear == 1
                app.NonlinearAnalisys.SelectedObject.Text = 'Yes';
            elseif nonlinear == 0
                app.NonlinearAnalisys.SelectedObject.Text = 'No';
            else
                app.NonlinearAnalisys.SelectedObject.Text = 'Yes';
                app.Message('non linearity', 'non linearity activated');
            end
            
            if negative == 1
                app.NegativePhase.SelectedObject.Text = 'Yes';
            elseif negative == 0
                app.NegativePhase.SelectedObject.Text = 'No';
            else
                app.NegativePhase.SelectedObject.Text = 'Yes';
                app.Message('negative phase', 'negative phase activated');
            end
            
            if option == 2
                app.DataBase.SelectedObject.Text = 'Abaco - Rigby Calibration';
            elseif option == 3
                app.DataBase.SelectedObject.Text = 'Abaco - Ana Calibration';
            elseif option == 4
                app.DataBase.SelectedObject.Text = 'Experimental Data';
            else
                app.DataBase.SelectedObject.Text = 'Abaco - Rigby Calibration';
                app.Message('Data Base', 'Abaco - Rigby Calibration');
            end
            
            % ----------------------------------------------------------- %
            
            % Close file
            
            fclose(fid);
            
        end
        
        function Message(~,stg1, stg2)
             msgbox(['The value of option of ' + stg1 + ' is not correct. \n' ...
                    'So, the default is ' + stg2],'Information','warn');       
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        app.app_pmax.Enable = false;
        app.app_pmin.Enable = false;
        app.app_td.Enable   = false;
        app.app_im.Enable   = false;
        app.app_id.Enable   = false;
        app.W_final.Enable  = false;
        app.interval.Enable = false;
        
        % ----------------------------------------------------------- %
        % Graph about plate geometric values
        
        [~, type_sup, ~, nonlinear, ~, ~, ~] = ...
            app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
            app.TypeExplosion.SelectedObject.Text, ...
            app.NonlinearAnalisys.SelectedObject.Text, ...
            app.NegativePhase.SelectedObject.Text, ...
            app.DataBase.SelectedObject.Text, app.Case.Value);
            
            Class_Plot.plotDraw...
                (app.UIAxes, app.app_a.Value, app.app_beta.Value, ...
                app.app_h.Value, nonlinear, type_sup, app.app_Z.Value, ...
                app.app_W.Value);
        end

        % Button pushed function: RunButton_General
        function RunButton_GeneralPushed(app, event)
            % ----------------------------------------------------------- %
            % Avaliating parameters
            
            [sup, type_sup, type, nonlinear, negative, option, ~] = ...
                app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            % ----------------------------------------------------------- %
            % Sending values to constructor method
            
            gen_button    = 0;
            adv_parameter = 1;
            
            % In this case, the avaliation of which button was clicked is
            % more important than the adv_parameter avaliation. But it is
            % necessaire to initialize adv_parameter 
            % because not generate a error in program.
            
            app.values_tnt = Class_TNT...
                (app.app_Z.Value, app.app_W.Value, type, negative, ...
                app.app_pmax.Value, app.app_pmin.Value, app.app_td.Value,...
                app.app_id.Value, app.app_im.Value, option);
            
            app.values_plate = Class_Plate...
                (sup, type_sup, app.app_a.Value, app.app_beta.Value, ...
                app.app_h.Value, app.app_nu.Value, app.app_E.Value, ...
                app.app_rho.Value);
            
            app.values_analysis = Class_Analysis...
                (nonlinear, app.TimeAnalysis.Value, gen_button);
            
            app.values_adv = Class_AdvAnalysis...
                (nonlinear, adv_parameter, app.app_Z.Value, app.app_W.Value, ...
                app.app_W.Value, 0, negative);
            
            % ----------------------------------------------------------- %
            % Avaliating parameters
            
            app.values_prt = Class_Parameters(app.values_tnt, ...
                app.values_analysis, app.values_adv);
            
            % ----------------------------------------------------------- %
            % Verificating the total time of analysis
            
            app.values_blast = Class_Blast(app.values_tnt, ...
                app.values_analysis, app.values_adv, app.values_prt);
            
            td              = app.values_blast.td;
            tm              = app.values_blast.tm;
            time            = app.values_analysis.time;
            total_time      = td + tm;
            
            if total_time > time
                vrf = 0;
            else
                vrf = 1;
            end
            
            % ----------------------------------------------------------- %
            % Avaliating parameters and calculate
            
            if vrf == 0
                
                msgbox...
                    (sprintf(['Total time of blast wave = %g s.' ...
                    'Please, insert a time of analysis bigger than total time.'], ...
                    total_time), 'Information', 'warn');
                
                app.values_blast = [];
                
            elseif vrf == 1
                
                % ----------------------------------------------------------- %
                
                % Message Box appears with the message 'Calculating'
                % without Ok button
                
                if ~exist('hideOk','var')
                    hideOk = 1;
                end
                
                msg = msgbox({'Calculating....Please, wait.';...
                    'This may take a few minutes.'},...
                    'Information','help');
                child = get(msg,'Children');
                
                if hideOk
                    delete(child(1)); % Removes ok button
                    drawnow;
                end
                
                % ----------------------------------------------------------- %
                
                % Calculating result
                
                app.values_blast    = Class_Blast(app.values_tnt, ...
                    app.values_analysis, app.values_adv, app.values_prt);
                
                app.values_k1k3     = Class_K1K3(app.values_plate, ...
                    app.values_analysis, app.values_adv, app.values_prt);
                
                app.values_disp     = Class_Disp(app.values_tnt, app.values_plate, ...
                    app.values_analysis, app.values_blast, app.values_k1k3, ...
                    app.values_adv, app.values_prt);
                
                app.values_stress   = Class_Stress(app.values_plate, ...
                    app.values_analysis, app.values_k1k3);
                
                app.values_strain   = Class_Strain(app.values_plate, ...
                    app.values_analysis, app.values_k1k3);
                
                app.values_period   = Class_Period(app.values_tnt, ...
                    app.values_plate, app.values_analysis, app.values_k1k3, ...
                    app.values_disp, app.values_prt);
                
                app.values_static   = Class_Static(app.values_tnt, ...
                    app.values_plate, app.values_analysis, app.values_blast, ...
                    app.values_k1k3);
                
                app.values_fft      = Class_FFT(app.values_blast, app.values_disp, ...
                    app.values_period, app.values_prt);
                
                app.values_result   = Class_Result(app.values_tnt, ...
                    app.values_plate, app.values_analysis, app.values_blast, ...
                    app.values_k1k3, app.values_disp, app.values_strain, ...
                    app.values_stress, app.values_fft);
                
                % Create a new table with results
                
                S                   = app.values_result.table;
                S2                  = app.values_result.table_fft;
                
                % ----------------------------------------------------------- %
                
                % Close Message box
                
                close(msg);
                
                % ----------------------------------------------------------- %
                
                % Open new window
                
                app.appGeneralResult = App_General_Result;
                
                % ----------------------------------------------------------- %
                
                % Applicating values in Table Database
                
                app.appGeneralResult.UITable.Data   = S;
                app.appGeneralResult.UITable2.Data  = S2;
                
                % ----------------------------------------------------------- %
                
                % Plate's parameters
                
                app.appGeneralResult.k1.Value                = double(app.values_k1k3.k1);
                app.appGeneralResult.k3.Value                = double(app.values_k1k3.k3);
                app.appGeneralResult.w_din_max.Value         = double(app.values_disp.major);
                app.appGeneralResult.w_sta_max.Value         = double(app.values_static.disp);
                app.appGeneralResult.linearperiod.Value      = double(app.values_period.TL);
                app.appGeneralResult.nonlinearperiod.Value   = double(app.values_period.TNL);
                app.appGeneralResult.totalperiod.Value       = double(app.values_period.RT);
                
                % Explosion's parameters
                
                app.appGeneralResult.pmax.Value              = app.values_blast.pmax;
                app.appGeneralResult.pmin.Value              = app.values_blast.pmin;
                app.appGeneralResult.td.Value                = app.values_blast.td;
                app.appGeneralResult.id.Value                = app.values_blast.id;
                app.appGeneralResult.im.Value                = app.values_blast.im;
                app.appGeneralResult.dec_coef.Value          = app.values_blast.expo;
                app.appGeneralResult.tm.Value                = app.values_blast.tm;
                
                % ----------------------------------------------------------- %
                
                % Ploting graphics
                
                draw = Class_Plot;
                
                draw.plotGraphic(app.appGeneralResult.UIAxes_Displacement, ...
                    nonlinear, type_sup, S(:,1), S(:,2), 'time (s)', 'u_z (m)');
                
                draw.plotGraphic(app.appGeneralResult.UIAxes_StrainX, ...
                    nonlinear, type_sup, S(:,1), S(:,3), 'time (s)', '\epsilon_x');
                
                draw.plotGraphic(app.appGeneralResult.UIAxes_StrainY, ...
                    nonlinear, type_sup, S(:,1), S(:,4), 'time (s)', '\epsilon_y');
                
                draw.plotGraphic(app.appGeneralResult.UIAxes_StressX, ...
                    nonlinear, type_sup, S(:,1), S(:,5), 'time (s)', '\sigma_x (MPa)');
                
                draw.plotGraphic(app.appGeneralResult.UIAxes_StressY, ...
                    nonlinear, type_sup, S(:,1), S(:,6), 'time (s)', '\sigma_y (MPa)');
                
                draw.plotGraphic(app.appGeneralResult.UIAxes_Pressure, ...
                    nonlinear, type_sup, S(:,1), S(:,7), 'time (s)', 'pressure (Pa)');
                
                draw.plotFFT(app.appGeneralResult.UIAxes_FFT, nonlinear, type_sup, ...
                    S2(:,1), S2(:,2), app.values_period.TNL, max(S2(:,2)), ...
                    '1 / \omega _N _L', 'FFT');
               
            end
        end

        % Button pushed function: RunButton_Adv
        function RunButton_AdvPushed(app, event)
            
            % ----------------------------------------------------------- %
            % Avaliating parameters
            
            [sup, type_sup, type, nonlinear, negative, option, parameter] = ...
                app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            % ----------------------------------------------------------- %
            % Sending values to constructor method
            
            gen_button              = 1;
            
            app.values_tnt          = Class_TNT...
                (app.app_Z.Value, app.app_W.Value, type, negative, ...
                app.app_pmax.Value, app.app_pmin.Value, app.app_td.Value,...
                app.app_id.Value, app.app_im.Value, option);
            
            app.values_plate        = Class_Plate...
                (sup, type_sup, app.app_a.Value, app.app_beta.Value, ...
                app.app_h.Value, app.app_nu.Value, app.app_E.Value, ...
                app.app_rho.Value);
            
            app.values_analysis     = Class_Analysis...
                (nonlinear, app.TimeAnalysis.Value, gen_button);
            
            if parameter == 1 || parameter == 4 || parameter == 5 || ...
                    parameter == 6 || parameter == 7 || parameter == 8
                
                app.values_adv = Class_AdvAnalysis(nonlinear, parameter, ...
                    app.app_Z.Value, app.app_W.Value, app.app_W.Value, ...
                    100, negative);
                
            elseif parameter == 2 || parameter == 3
                
                app.values_adv = Class_AdvAnalysis(nonlinear, parameter, ...
                    app.app_Z.Value, app.app_W.Value, app.W_final.Value, ...
                    app.interval.Value, negative);
                
            end
            
            % ----------------------------------------------------------- %
            % Avaliating parameters
            
            app.values_prt = Class_Parameters(app.values_tnt, ...
                app.values_analysis, app.values_adv);
            
            % ----------------------------------------------------------- %
            % Verificating time values
            
            app.values_blast = Class_Blast(app.values_tnt, ...
                app.values_analysis, app.values_adv, app.values_prt);
            
            td              = app.values_blast.td;
            tm              = app.values_blast.tm;
            time            = app.values_analysis.time;
            total_time      = td + tm;
            
            if total_time > time
                vrf = 0;
            else
                vrf = 1;
            end
            
            % ----------------------------------------------------------- %
            % Avaliating parameters and calculate
            
            if vrf == 0
                
                msgbox...
                    (sprintf(['Total time of blast wave = %g s.' ...
                    'Please, insert a time of analysis bigger than total time.'], ...
                    total_time), 'Information', 'warn');
                
                app.values_blast = [];
                
            elseif vrf == 1
                
                % ----------------------------------------------------------- %
                
                % Message Box appears with the message 'Calculating'
                % without Ok button
                
                if ~exist('hideOk','var')
                    hideOk = 1;
                end
                
                msg = msgbox({'Calculating....Please, wait.';...
                    'This may take a few minutes.'},...
                    'Information','help');
                child = get(msg,'Children');
                
                if hideOk
                    delete(child(1)); % Removes ok button
                    drawnow;
                end
                
                % ----------------------------------------------------------- %
                
                % Calculating result
                
                app.values_blast    = Class_Blast(app.values_tnt, ...
                    app.values_analysis, app.values_adv, app.values_prt);
                
                app.values_k1k3     = Class_K1K3(app.values_plate, ...
                    app.values_analysis, app.values_adv, app.values_prt);
                
                app.values_disp     = Class_Disp(app.values_tnt, ...
                    app.values_plate, app.values_analysis, app.values_blast, ...
                    app.values_k1k3, app.values_adv, app.values_prt);
                
                app.values_period   = Class_Period(app.values_tnt, ...
                    app.values_plate, app.values_analysis, app.values_k1k3, ...
                    app.values_disp, app.values_prt);
                
                app.values_calcadv  = Class_Advanced(app.values_tnt, app.values_plate, ...
                    app.values_analysis, app.values_adv, app.values_k1k3, ...
                    app.values_disp, app.values_period, app.values_prt);
                
                S                   = app.values_calcadv.matrix_adv;
                
                % --------------------------------------------------------------- %
                
                % General Equation coefficients
                
                A                   = app.values_calcadv.A;
                B                   = app.values_calcadv.B;
                C                   = app.values_calcadv.C;
                D                   = app.values_calcadv.D;
                E                   = app.values_calcadv.E;
                F                   = app.values_calcadv.F;
                
                % ----------------------------------------------------------- %
                
                % Close Message box
                
                close(msg);
                
                % --------------------------------------------------------------- %
                
                % Open new window
                
                app.appAdvanced = App_Advanced_Analysis;
                
                % --------------------------------------------------------------- %
                
                % Applicating the values in Table Database
                
                app.appAdvanced.UITable.Data = S;
                
                % --------------------------------------------------------------- %
                
                % Verificating the parameter
                
                parameter = app.values_adv.adv_parameter;
                
                draw = Class_Plot;
                
                switch parameter
                    
                    case 1
                        app.appAdvanced.UITable.ColumnName = {'Z m/kg^(1/3)', 'u_z / h'};
                        
                        draw.plotGraphic(app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                            S(:,1), S(:,2), 'Z (m / kg ^1 ^/ ^3)', 'u_z / h');
                        
                    case 2
                        app.appAdvanced.UITable.ColumnName = {'W (kg)', 'u_L / u_z (without N.)', ...
                            'u_L / u_z (with N.)'};
                        
                        draw.plotGraphic2(app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                            S(:,1), S(:,2), S(:,3),'W _T _N _T (kg)', 'u_L / u_z', ...
                            ' without Negative Phase', ' with Negative Phase');
                        
                    case 3
                        app.appAdvanced.UITable.ColumnName = {'t_d / T_L', 'DAF'};
                        
                        draw.plotGraphic(app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                            S(:,1), S(:,2),'t_d / T_L', 'DAF');
                        
                    case 4
                        app.appAdvanced.UITable.ColumnName = {'t_d / T_L', '(t_d + t_m) / T_L', 'u_z / h'};
                        
                        draw.plotGraphic(app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                            S(:,1), S(:,3), 't_d / T_L', 'u_z / h');
                        
                    case 5
                        app.appAdvanced.UITable.ColumnName = {'t_d / T_NL', ...
                            't_d + tm / T_NL', 'T_NL', 'u_z / h'};
                        
                        draw.plotGraphic(app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                            S(:,1), S(:,4), 't_d / T_NL', 'u_z / h');
                        
                    case 6
                        app.appAdvanced.UITable.ColumnName = {'Z', '\sigma_x_x', ...
                            '\sigma_y_y', 'u_z / h'};
                        
                        draw.plotGraphic2(app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                            S(:,4), S(:,2), S(:,3),'u_z / h', '\sigma_m / (\sigma_m + \sigma_b)', ...
                            ' \sigma_x_x', ' \sigma_y_y');
                        
                    case 7
                        app.appAdvanced.UITable.ColumnName = {'t_d / T_L', 'DAF'};
                        
                        draw.plotGraphic(app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                            S(:,1), S(:,2),'t_d / T_L', 'DAF');
                        
                    case 8
                        app.appAdvanced.UITable.ColumnName = {'Z', 'W = 1 kg', ...
                            'W = 2 kg', 'W = 3 kg', 'W = 4 kg', 'W = 5 kg', ...
                            'W = 6 kg', 'W = 7 kg', 'W = 8 kg', 'W = 9 kg', ...
                            'W = 10 kg'};
                        
                        draw.plotGraphic4(app.appAdvanced.UIAxes, nonlinear, type_sup, ...
                            S(:,1), S(:,2), S(:,3), S(:,6), S(:,11), ...
                            'Z (m / kg ^1 ^/ ^3)', 'u_z / h', ' - W = 1 kg', ' - W = 2 kg', ...
                            ' - W = 5 kg', ' - W = 10 kg');
                        
                        % --------------------------------------------------------------- %
                        
                        % Save file General Equation
                        filterspec = {'*.txt'};
                        [filename,pathname] = uiputfile...
                            (filterspec,'Dynablast Program - General Equation');
                        if ~isequal(filename,0)
                            fullname = strcat(pathname,filename);
                            saveEquation(app,fullname, A, B, C, D, E, F)
                        end
                        
                end
                
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            filterspec = {'*.txt'};
            [filename,pathname] = uiputfile(filterspec,'Dynablast Program');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                saveFile(app,fullname)
            end
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            filterspec = {'*.txt'};
            [filename,pathname] = uigetfile(filterspec,'Dynablast Program');
            if ~isequal(filename,0)
                fullname = strcat(pathname,filename);
                openFile(app,fullname);
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            App_Help;
        end

        % Menu selected function: AboutSoftwareMenu
        function AboutSoftwareMenuSelected(app, event)
            App_About;
        end

        % Value changed function: app_a
        function app_aValueChanged(app, event)
            [~, type_sup, ~, nonlinear, ~, ~, ~] = ...
                app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            Class_Plot.plotDraw...
                (app.UIAxes, app.app_a.Value, app.app_beta.Value, ...
                app.app_h.Value, nonlinear, type_sup, app.app_Z.Value, ...
                app.app_W.Value);
        end

        % Value changed function: SupportMembrane
        function SupportMembraneValueChanged(app, event)
            [~, type_sup, ~, nonlinear, ~, ~, ~] = ...
                app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            Class_Plot.plotDraw...
                (app.UIAxes, app.app_a.Value, app.app_beta.Value, ...
                app.app_h.Value, nonlinear, type_sup, app.app_Z.Value, ...
                app.app_W.Value);
            
        end

        % Value changed function: app_beta
        function app_betaValueChanged(app, event)
            [~, type_sup, ~, nonlinear, ~, ~, ~] = ...
                app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            Class_Plot.plotDraw...
                (app.UIAxes, app.app_a.Value, app.app_beta.Value, ...
                app.app_h.Value, nonlinear, type_sup, app.app_Z.Value, ...
                app.app_W.Value);
        end

        % Value changed function: app_h
        function app_hValueChanged(app, event)
            [~, type_sup, ~, nonlinear, ~, ~, ~] = ...
                app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            Class_Plot.plotDraw...
                (app.UIAxes, app.app_a.Value, app.app_beta.Value, ...
                app.app_h.Value, nonlinear, type_sup, app.app_Z.Value, ...
                app.app_W.Value);
        end

        % Value changed function: app_W
        function app_WValueChanged(app, event)
            [~, type_sup, ~, nonlinear, ~, ~, ~] = ...
                app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            Class_Plot.plotDraw...
                (app.UIAxes, app.app_a.Value, app.app_beta.Value, ...
                app.app_h.Value, nonlinear, type_sup, app.app_Z.Value, ...
                app.app_W.Value);
        end

        % Value changed function: app_Z
        function app_ZValueChanged(app, event)
            [~, type_sup, ~, nonlinear, ~, ~, ~] = ...
                app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            Class_Plot.plotDraw...
                (app.UIAxes, app.app_a.Value, app.app_beta.Value, ...
                app.app_h.Value, nonlinear, type_sup, app.app_Z.Value, ...
                app.app_W.Value);
        end

        % Selection changed function: NonlinearAnalisys
        function NonlinearAnalisysSelectionChanged(app, event)
            [~, type_sup, ~, nonlinear, ~, ~, ~] = ...
                app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            Class_Plot.plotDraw...
                (app.UIAxes, app.app_a.Value, app.app_beta.Value, ...
                app.app_h.Value, nonlinear, type_sup, app.app_Z.Value, ...
                app.app_W.Value);
        end

        % Selection changed function: DataBase
        function DataBaseSelectionChanged(app, event)
            
            [~, ~, ~, ~, ~, option, ~] = ...
                app.Avaliating(app.SupportPlate.Value, app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            if option == 2 || option == 3
                
                app.app_W.Enable            = true;
                app.app_Z.Enable            = true;
                app.app_pmax.Enable         = false;
                app.app_pmin.Enable         = false;
                app.app_td.Enable           = false;
                app.app_im.Enable           = false;
                app.app_id.Enable           = false;
                app.RunButton_Adv.Enable    = true;
                
            elseif option == 4
                
                app.app_W.Enable            = false;
                app.app_Z.Enable            = false;
                app.app_pmax.Enable         = true;
                app.app_pmin.Enable         = true;
                app.app_td.Enable           = true;
                app.app_im.Enable           = true;
                app.app_id.Enable           = true;
                app.RunButton_Adv.Enable    = false;
                
            end
            
        end

        % Value changed function: Case
        function CaseValueChanged(app, event)
            [~, ~, ~, ~, ~, ~, parameter] = ...
                app.Avaliating(app.SupportPlate.Value, ...
                app.SupportMembrane.Value, ...
                app.TypeExplosion.SelectedObject.Text, ...
                app.NonlinearAnalisys.SelectedObject.Text, ...
                app.NegativePhase.SelectedObject.Text, ...
                app.DataBase.SelectedObject.Text, app.Case.Value);
            
            if parameter == 1 || parameter == 4 || parameter == 5 ...
                    || parameter == 6 || parameter == 7 || parameter == 8
                app.W_final.Enable = false;
                app.interval.Enable = false;
            elseif parameter == 2 || parameter == 3
                app.W_final.Enable = true;
                app.interval.Enable = true;
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DYNAblastUIFigure = uifigure('Visible', 'off');
            app.DYNAblastUIFigure.Name = 'DYNAblast';
            app.FileMenu = uimenu(app.DYNAblastUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.AboutMenu = uimenu(app.DYNAblastUIFigure);
            app.AboutMenu.Text = 'About';
            app.HelpMenu = uimenu(app.AboutMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.AboutSoftwareMenu = uimenu(app.AboutMenu);
            app.AboutSoftwareMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutSoftwareMenuSelected, true);
            app.AboutSoftwareMenu.Text = 'About Software';
            app.UIAxes = uiaxes(app.DYNAblastUIFigure);
            app.RunButton_General = uibutton(app.DYNAblastUIFigure, 'push');
            app.RunButton_General.ButtonPushedFcn = createCallbackFcn(app, @RunButton_GeneralPushed, true);
            app.RunButton_General.Text = 'Dynamic Analysis';
            app.RunButton_Adv = uibutton(app.DYNAblastUIFigure, 'push');
            app.RunButton_Adv.ButtonPushedFcn = createCallbackFcn(app, @RunButton_AdvPushed, true);
            app.RunButton_Adv.Text = 'Advanced Analysis';
            app.PlatePanel = uipanel(app.DYNAblastUIFigure);
            app.PlatePanel.Title = 'Plate';
            app.SupportPlate = uidropdown(app.PlatePanel);
            app.SupportPlate.Items = {'Simple Support', 'Campled Support'};
            app.SupportPlate.Value = 'Simple Support';
            app.SupportMembrane = uidropdown(app.PlatePanel);
            app.SupportMembrane.Items = {'Immovable', 'Movable', 'Stress Free'};
            app.SupportMembrane.ValueChangedFcn = createCallbackFcn(app, @SupportMembraneValueChanged, true);
            app.SupportMembrane.Value = 'Immovable';
            app.BoundaryConditionsLabel = uilabel(app.PlatePanel);
            app.BoundaryConditionsLabel.Text = 'Boundary Conditions';
            app.GeometricCharacteristicsLabel = uilabel(app.PlatePanel);
            app.GeometricCharacteristicsLabel.Text = 'Geometric Characteristics';
            app.LengthatxaxismLabel = uilabel(app.PlatePanel);
            app.LengthatxaxismLabel.Text = 'Length at x axis (m):';
            app.betaLabel = uilabel(app.PlatePanel);
            app.betaLabel.Text = 'beta:';
            app.app_a = uieditfield(app.PlatePanel, 'numeric');
            app.app_a.Limits = [0.0001 Inf];
            app.app_a.ValueChangedFcn = createCallbackFcn(app, @app_aValueChanged, true);
            app.app_a.Value = 0.508;
            app.app_beta = uieditfield(app.PlatePanel, 'numeric');
            app.app_beta.Limits = [0.0001 Inf];
            app.app_beta.ValueChangedFcn = createCallbackFcn(app, @app_betaValueChanged, true);
            app.app_beta.Value = 1;
            app.ThicknessmLabel = uilabel(app.PlatePanel);
            app.ThicknessmLabel.Text = 'Thickness (m):';
            app.app_h = uieditfield(app.PlatePanel, 'numeric');
            app.app_h.Limits = [0.0001 Inf];
            app.app_h.ValueChangedFcn = createCallbackFcn(app, @app_hValueChanged, true);
            app.app_h.Value = 0.0034;
            app.PhisicalCharacteristicsLabel = uilabel(app.PlatePanel);
            app.PhisicalCharacteristicsLabel.Text = 'Phisical Characteristics';
            app.PoissonscoefficientLabel = uilabel(app.PlatePanel);
            app.PoissonscoefficientLabel.Text = 'Poisson''s coefficient:';
            app.YoungsModulusENmLabel = uilabel(app.PlatePanel);
            app.YoungsModulusENmLabel.Text = {'Young''s Modulus E (N/m²):'; ''};
            app.MaterialsdensitykgmLabel = uilabel(app.PlatePanel);
            app.MaterialsdensitykgmLabel.Text = 'Material''s density (kg/m³):';
            app.app_nu = uieditfield(app.PlatePanel, 'numeric');
            app.app_nu.Limits = [0.0001 1];
            app.app_nu.Value = 0.3;
            app.app_E = uieditfield(app.PlatePanel, 'numeric');
            app.app_E.Limits = [0.0001 Inf];
            app.app_E.Value = 207000000000;
            app.app_rho = uieditfield(app.PlatePanel, 'numeric');
            app.app_rho.Limits = [0.0001 Inf];
            app.app_rho.Value = 7770;
            app.AnalysisPanel = uipanel(app.DYNAblastUIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.TabGroup = uitabgroup(app.AnalysisPanel);
            app.GeneralAnalysisTab = uitab(app.TabGroup);
            app.GeneralAnalysisTab.Title = 'General Analysis';
            app.NonlinearAnalysisLabel = uilabel(app.GeneralAnalysisTab);
            app.NonlinearAnalysisLabel.Text = 'Nonlinear Analysis';
            app.NonlinearAnalisys = uibuttongroup(app.GeneralAnalysisTab);
            app.NonlinearAnalisys.SelectionChangedFcn = createCallbackFcn(app, @NonlinearAnalisysSelectionChanged, true);
            app.YesButton = uiradiobutton(app.NonlinearAnalisys);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.NonlinearAnalisys);
            app.NoButton.Text = 'No';
            app.NegativePhaseLabel = uilabel(app.GeneralAnalysisTab);
            app.NegativePhaseLabel.Text = 'Negative Phase';
            app.NegativePhase = uibuttongroup(app.GeneralAnalysisTab);
            app.YesButton_2 = uiradiobutton(app.NegativePhase);
            app.YesButton_2.Text = 'Yes';
            app.YesButton_2.Value = true;
            app.NoButton_2 = uiradiobutton(app.NegativePhase);
            app.NoButton_2.Text = 'No';
            app.TimeofAnalysisLabel = uilabel(app.GeneralAnalysisTab);
            app.TimeofAnalysisLabel.Text = 'Time of Analysis';
            app.timesLabel = uilabel(app.GeneralAnalysisTab);
            app.timesLabel.Text = 'time (s):';
            app.TimeAnalysis = uieditfield(app.GeneralAnalysisTab, 'numeric');
            app.TimeAnalysis.Limits = [0 Inf];
            app.TimeAnalysis.Value = 0.08;
            app.AdvancedAnalysisTab = uitab(app.TabGroup);
            app.AdvancedAnalysisTab.Title = 'Advanced Analysis';
            app.txt_mass_case2 = uilabel(app.AdvancedAnalysisTab);
            app.txt_mass_case2.Text = 'Final TNT''s mass (kg):';
            app.W_final = uieditfield(app.AdvancedAnalysisTab, 'numeric');
            app.W_final.Limits = [0 Inf];
            app.txt_interval_case2 = uilabel(app.AdvancedAnalysisTab);
            app.txt_interval_case2.Text = 'Number of intervals:';
            app.interval = uieditfield(app.AdvancedAnalysisTab, 'numeric');
            app.interval.Limits = [0 Inf];
            app.Case = uidropdown(app.AdvancedAnalysisTab);
            app.Case.Items = {'Case 1 - Z x uz / h', 'Case 2 - W x uz / h', 'Case 3 - td / TL x DAF - Variating W', 'Case 4 - td / TL x uz / h', 'Case 5 - td / TNL x uz / h', 'Case 6 - uz / h x stress', 'Case 7 - td / TL x DAF - Variating Z', 'Case 8 - General Equation'};
            app.Case.ValueChangedFcn = createCallbackFcn(app, @CaseValueChanged, true);
            app.Case.Value = 'Case 1 - Z x uz / h';
            app.CaseofAnalysisLabel = uilabel(app.AdvancedAnalysisTab);
            app.CaseofAnalysisLabel.Text = 'Case of Analysis';
            app.ButtonGroup = uibuttongroup(app.AdvancedAnalysisTab);
            app.W1to10kgButton = uiradiobutton(app.ButtonGroup);
            app.W1to10kgButton.Text = 'W = 1 to 10 kg';
            app.W1to10kgButton.Value = true;
            app.W100to1000kgButton = uiradiobutton(app.ButtonGroup);
            app.W100to1000kgButton.Text = 'W = 100 to 1000 kg';
            app.TNTPanel = uipanel(app.DYNAblastUIFigure);
            app.TNTPanel.Title = 'TNT';
            app.DataBase = uibuttongroup(app.TNTPanel);
            app.DataBase.SelectionChangedFcn = createCallbackFcn(app, @DataBaseSelectionChanged, true);
            app.AbacoRigbyCalibrationButton_2 = uiradiobutton(app.DataBase);
            app.AbacoRigbyCalibrationButton_2.Text = 'Abaco - Rigby Calibration';
            app.AbacoRigbyCalibrationButton_2.Value = true;
            app.ExperimentalDataButton = uiradiobutton(app.DataBase);
            app.ExperimentalDataButton.Text = 'Experimental Data';
            app.AbacoAnaCalibrationButton = uiradiobutton(app.DataBase);
            app.AbacoAnaCalibrationButton.Text = 'Abaco - Ana Calibration';
            app.TypeofExplosionLabel = uilabel(app.TNTPanel);
            app.TypeofExplosionLabel.Text = 'Type of Explosion';
            app.TypeExplosion = uibuttongroup(app.TNTPanel);
            app.SphericalButton = uiradiobutton(app.TypeExplosion);
            app.SphericalButton.Text = 'Spherical';
            app.SphericalButton.Value = true;
            app.HemisphericalButton = uiradiobutton(app.TypeExplosion);
            app.HemisphericalButton.Text = 'Hemispherical';
            app.TotalMasskgLabel = uilabel(app.TNTPanel);
            app.TotalMasskgLabel.Text = 'Total Mass (kg):';
            app.CharacteristicsLabel = uilabel(app.TNTPanel);
            app.CharacteristicsLabel.Text = 'Characteristics';
            app.ScalarDistanceZkgm13Label = uilabel(app.TNTPanel);
            app.ScalarDistanceZkgm13Label.Text = 'Scalar Distance Z (kg/m^1/3):';
            app.app_W = uieditfield(app.TNTPanel, 'numeric');
            app.app_W.Limits = [0.0001 Inf];
            app.app_W.ValueChangedFcn = createCallbackFcn(app, @app_WValueChanged, true);
            app.app_W.Value = 0.24;
            app.app_Z = uieditfield(app.TNTPanel, 'numeric');
            app.app_Z.Limits = [0.0001 38];
            app.app_Z.ValueChangedFcn = createCallbackFcn(app, @app_ZValueChanged, true);
            app.app_Z.Value = 5.64;
            app.MaxSobrepressurePaLabel = uilabel(app.TNTPanel);
            app.MaxSobrepressurePaLabel.Text = 'Max Sobrepressure (Pa):';
            app.MaxUnderpressurePaLabel = uilabel(app.TNTPanel);
            app.MaxUnderpressurePaLabel.Text = 'Max Underpressure (Pa):';
            app.PositivetimesLabel = uilabel(app.TNTPanel);
            app.PositivetimesLabel.Text = 'Positive time (s):';
            app.app_pmax = uieditfield(app.TNTPanel, 'numeric');
            app.app_pmax.Limits = [0 Inf];
            app.app_pmax.Value = 57086.09;
            app.app_pmin = uieditfield(app.TNTPanel, 'numeric');
            app.app_pmin.Limits = [0 Inf];
            app.app_pmin.Value = 15420.247;
            app.app_td = uieditfield(app.TNTPanel, 'numeric');
            app.app_td.Limits = [0 1];
            app.app_td.Value = 0.00202;
            app.PositiveImpulsePasLabel = uilabel(app.TNTPanel);
            app.PositiveImpulsePasLabel.Text = 'Positive Impulse (Pa.s):';
            app.app_id = uieditfield(app.TNTPanel, 'numeric');
            app.app_id.Limits = [0 Inf];
            app.app_id.Value = 37.3129;
            app.NegativeImpulsePasLabel = uilabel(app.TNTPanel);
            app.NegativeImpulsePasLabel.Text = 'Negative Impulse (Pa.s):';
            app.app_im = uieditfield(app.TNTPanel, 'numeric');
            app.app_im.Limits = [0 Inf];
            app.app_im.Value = 40.536;
            app.DataBaseLabel_3 = uilabel(app.TNTPanel);
            app.DataBaseLabel_3.Text = 'Data Base';
            % Show the figure after all components are created
            app.DYNAblastUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Blast

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DYNAblastUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DYNAblastUIFigure)
        end
    end
end